classdef heateqnsolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        NextButton                      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        HeatEquationSolverLabel         matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        NextButton_2                    matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        PoweroftheheatsourceWEditField  matlab.ui.control.NumericEditField
        PoweroftheheatsourceWEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityEditField    matlab.ui.control.NumericEditField
        ThermalConductivityEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        TemperatureontheLowerSideEditField  matlab.ui.control.NumericEditField
        TemperatureontheLowerSideEditFieldLabel  matlab.ui.control.Label
        TemperatureontheRightSideEditField  matlab.ui.control.NumericEditField
        TemperatureontheRightSideEditFieldLabel  matlab.ui.control.Label
        TemperatureontheUpperSideEditField  matlab.ui.control.NumericEditField
        TemperatureontheUpperSideEditFieldLabel  matlab.ui.control.Label
        TemperatureontheLeftSideEditField  matlab.ui.control.NumericEditField
        TemperatureontheLeftSideEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        PositionoftheheatsourceLabel    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        HeightoftheRectangularplateEditField  matlab.ui.control.NumericEditField
        HeightoftheRectangularplateEditFieldLabel  matlab.ui.control.Label
        WidthoftheRectangularplateEditField  matlab.ui.control.NumericEditField
        WidthoftheRectangularplateEditFieldLabel  matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        EditField_3                     matlab.ui.control.EditField
        ChooseoneoptionDropDown         matlab.ui.control.DropDown
        ChooseoneoptionDropDownLabel    matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        DevelopersTab                   matlab.ui.container.Tab
        ExitButton                      matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
    end


    methods (Access = private)

        function D3plotgraph(app,h,W,H,T)

            x = 0:h:W;
            y = 0:h:H;
            c = colorbar(app.UIAxes)
            colormap(app.UIAxes, jet)
            surf(app.UIAxes, x,y,T');

        end
        function D2plotgraph(app,h,W,H,T)

            x = 0:h:W;
            y = 0:h:H;
            c = colorbar(app.UIAxes)
            colormap(app.UIAxes, jet)
            contourf(app.UIAxes,x,y,T');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParametersTab;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.PlotTab;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.DevelopersTab;
        end

        % Callback function
        function NextButton_4Pushed(app, event)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Geometry of Domain
            W = app.WidthoftheRectangularplateEditField.Value;
            H = app.HeightoftheRectangularplateEditField.Value;
            
            Nx = 51;

            h = min(W,H)/(Nx-1);

            Nx = round(W/h) + 1;
            Ny = round(H/h) + 1 ;

            W = (Nx -1)*h;
            H = (Ny -1)*h;
            a = app.xEditField.Value;

            b = app.yEditField.Value;

            P = app.PoweroftheheatsourceWEditField.Value;
            q = @(x,y)((x-a).^2+(y-b).^2);
            %% Boundary and Initial Conditons
            T = 25*ones(Nx,Ny);
            TL = app.TemperatureontheLeftSideEditField.Value;
            TR = app.TemperatureontheRightSideEditField.Value;
            TT = app.TemperatureontheUpperSideEditField.Value;
            TB = app.TemperatureontheLowerSideEditField.Value;
            T(1,2:Ny-1) = TL;
            T(2:Nx-1,Ny) = TT;
            T(Nx,2:Ny-1) = TR;
            T(2:Nx-1,1) = TB;

            T(1,1) = (TL+TB)/2;
            T(1,Ny) = (TL+TT)/2;
            T(Nx,Ny) = (TR+TT)/2;
            T(Nx,1) = (TR+TB)/2;
            Iter = 0;
            k =app.ThermalConductivityEditField.Value;
            Epsilon = 1e-5;
            Error = 50;
            x = 0:h:W;
            y = 0:h:H;

            while (Error>Epsilon)
                Iter = Iter+1;
                Told = T;
                for j = 2 : Ny-1
                    for i = 2:Nx-1
                        g = q(x(i), y(j));
                        if g == 0
                            g = (q(x(i-1), y(j)) + q(x(i), y(j-1)) + q(x(i+1), y(j)) + q(x(i), y(j+1))) / 4;
                        end
                        g = P / (4*pi*g);
                        T(i,j) = (T(i+1,j)+T(i-1,j)+T(i,j+1)+T(i,j-1))/4 + g/(4*k)*h^2  ;
                    end
                end
                Error = sqrt(sum(sum((T-Told).^2)));

            end
            %% Plotting the results
            value = app.ChooseoneoptionDropDown.Value
            if(strcmp(value,'2-D Plot')==1)
                D2plotgraph(app,h,W,H,T);
                app.EditField_3.Value = 'Temparature Distribution.';

            elseif(strcmp(value,'3-D Plot')==1)
                D3plotgraph(app,h,W,H,T);
                app.EditField_3.Value = '3-D Plot of T';

            end


        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)

        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)

        end

        % Callback function
        function PlotButton_2Pushed(app, event)

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Ask the user if they want to exit the program
            choice = questdlg('Are you sure you want to exit the program?', ...
                'Exit Confirmation', ...
                'Yes','No','No');
            % If the user clicks "Yes", close the app window and exit the program
            if strcmp(choice, 'Yes')
                delete(app.UIFigure);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HeatEquationSolverLabel = uilabel(app.HomeTab);
            app.HeatEquationSolverLabel.Text = 'Heat Equation Solver';
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'This app will solve the steady state heat conduction equation with heat generation using numerical method in 2-D space.'; ''};
            app.NextButton = uibutton(app.HomeTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.Panel = uipanel(app.ParametersTab);
            app.WidthoftheRectangularplateEditFieldLabel = uilabel(app.Panel);
            app.WidthoftheRectangularplateEditFieldLabel.Text = 'Width of the Rectangular plate';
            app.WidthoftheRectangularplateEditField = uieditfield(app.Panel, 'numeric');
            app.HeightoftheRectangularplateEditFieldLabel = uilabel(app.Panel);
            app.HeightoftheRectangularplateEditFieldLabel.Text = 'Height of the Rectangular plate';
            app.HeightoftheRectangularplateEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.ParametersTab);
            app.PositionoftheheatsourceLabel = uilabel(app.Panel_2);
            app.PositionoftheheatsourceLabel.Text = 'Position of the heat source:';
            app.xEditFieldLabel = uilabel(app.Panel_2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Panel_2, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.Panel_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel_2, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.Panel_3 = uipanel(app.ParametersTab);
            app.TemperatureontheLeftSideEditFieldLabel = uilabel(app.Panel_3);
            app.TemperatureontheLeftSideEditFieldLabel.Text = 'Temperature on the Left Side';
            app.TemperatureontheLeftSideEditField = uieditfield(app.Panel_3, 'numeric');
            app.TemperatureontheUpperSideEditFieldLabel = uilabel(app.Panel_3);
            app.TemperatureontheUpperSideEditFieldLabel.Text = 'Temperature on the Upper Side';
            app.TemperatureontheUpperSideEditField = uieditfield(app.Panel_3, 'numeric');
            app.TemperatureontheRightSideEditFieldLabel = uilabel(app.Panel_3);
            app.TemperatureontheRightSideEditFieldLabel.Text = 'Temperature on the Right Side';
            app.TemperatureontheRightSideEditField = uieditfield(app.Panel_3, 'numeric');
            app.TemperatureontheLowerSideEditFieldLabel = uilabel(app.Panel_3);
            app.TemperatureontheLowerSideEditFieldLabel.Text = 'Temperature on the Lower Side';
            app.TemperatureontheLowerSideEditField = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.ParametersTab);
            app.ThermalConductivityEditFieldLabel = uilabel(app.Panel_4);
            app.ThermalConductivityEditFieldLabel.Text = 'Thermal Conductivity';
            app.ThermalConductivityEditField = uieditfield(app.Panel_4, 'numeric');
            app.ThermalConductivityEditField.ValueDisplayFormat = '%e';
            app.PoweroftheheatsourceWEditFieldLabel = uilabel(app.Panel_4);
            app.PoweroftheheatsourceWEditFieldLabel.Text = 'Power of the heat source(W)';
            app.PoweroftheheatsourceWEditField = uieditfield(app.Panel_4, 'numeric');
            app.NextButton_2 = uibutton(app.ParametersTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = ' Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'T')
            colormap(app.UIAxes, 'jet')
            app.NextButton_3 = uibutton(app.PlotTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ChooseoneoptionDropDownLabel = uilabel(app.PlotTab);
            app.ChooseoneoptionDropDownLabel.Text = 'Choose one option';
            app.ChooseoneoptionDropDown = uidropdown(app.PlotTab);
            app.ChooseoneoptionDropDown.Items = {'2-D Plot', '3-D Plot'};
            app.ChooseoneoptionDropDown.Value = '2-D Plot';
            app.EditField_3 = uieditfield(app.PlotTab, 'text');
            app.DevelopersTab = uitab(app.TabGroup);
            app.DevelopersTab.Title = 'Developers';
            app.TextArea_2 = uitextarea(app.DevelopersTab);
            app.TextArea_2.Value = {'Md.Rifat Rahman'; 'ID:2006137'; 'Section:C1'; 'Department:EEE'};
            app.TextArea_3 = uitextarea(app.DevelopersTab);
            app.TextArea_3.Value = {'Samia Hossain'; 'ID:2006138'; 'Section:C1'; 'Department:EEE'};
            app.ExitButton = uibutton(app.DevelopersTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = heateqnsolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end